classdef Face_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        ExitButton                 matlab.ui.control.Button
        LoadImageButton            matlab.ui.control.Button
        DetectFaceButton           matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        DetecitonSuccessLampLabel  matlab.ui.control.Label
        DetecitonSuccessLamp       matlab.ui.control.Lamp
        FaceDetectionAppLabel      matlab.ui.control.Label
    end


    properties (Access = private)
        img % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Callback function: LoadImageButton

     [file, path] = uigetfile({'*.png;*.jpg;*.jpeg', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            app.img = imread(fullfile(path, file));
            app.Image.ImageSource = app.img;

        end

        % Button pushed function: DetectFaceButton
        function DetectFaceButtonPushed(app, event)
            % Callback function: DetectFaceButton
    if isempty(app.img)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end

            smoothImg = imgaussfilt(app.img, 1);
            ycbcr = rgb2ycbcr(smoothImg);
            y = ycbcr(:,:,1); cb = ycbcr(:,:,2); cr = ycbcr(:,:,3);
            skinMask = (cb >= 77 & cb <= 127) & (cr >= 133 & cr <= 173) & (y > 40);
            skinMask(round(end*0.6):end, :) = 0;
            skinMask = imfill(skinMask, 'holes');
            skinMask = bwareaopen(skinMask, 500);
            props = regionprops(skinMask, 'BoundingBox', 'Image', 'Eccentricity');

            imgWithBoxes = app.img;
            found = false;

            for i = 1:length(props)
                patch = props(i).Image;
                [h, w] = size(patch);
                [X, Y] = meshgrid(1:w, 1:h);
                cx = w / 2; cy = h / 2;
                ellipseMask = ((X - cx).^2)/(w/2)^2 + ((Y - cy).^2)/(h/2)^2 <= 1;

                overlapRatio = sum(patch(:) & ellipseMask(:)) / sum(ellipseMask(:));
                leftHalf = patch(:, 1:floor(w/2));
                rightHalf = fliplr(patch(:, end-floor(w/2)+1:end));
                if size(leftHalf,2) ~= size(rightHalf,2), continue; end
                symmetryScore = sum(leftHalf(:) == rightHalf(:)) / numel(leftHalf);

                if overlapRatio > 0.6 && symmetryScore > 0.75 && props(i).Eccentricity < 0.92
                    imgWithBoxes = insertShape(imgWithBoxes, 'Rectangle', props(i).BoundingBox, ...
                        'Color', 'green', 'LineWidth', 3);
                    found = true;
                end
            end

            app.Image.ImageSource = imgWithBoxes;
            app.DetecitonSuccessLamp.Color = [0 1 0 ];

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % Callback function: ResetButton
    app.img = [];  % ممكن تخليه فاضي لو حابب
blankImage = ones(100,100,3);  % صورة بيضاء 100x100
app.Image.ImageSource = blankImage;
app.DetecitonSuccessLamp.Color = [1 0 0];  % خلي اللمبة حمرا

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            % Callback function: ExitButton
     delete(app.UIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.ExitButton = uibutton(app.Panel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit ';
            app.LoadImageButton = uibutton(app.Panel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image ';
            app.DetectFaceButton = uibutton(app.Panel, 'push');
            app.DetectFaceButton.ButtonPushedFcn = createCallbackFcn(app, @DetectFaceButtonPushed, true);
            app.DetectFaceButton.Text = 'Detect Face ';
            app.ResetButton = uibutton(app.Panel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DetecitonSuccessLampLabel = uilabel(app.Panel);
            app.DetecitonSuccessLampLabel.Text = 'Deteciton Success ';
            app.DetecitonSuccessLamp = uilamp(app.Panel);
            app.FaceDetectionAppLabel = uilabel(app.Panel);
            app.FaceDetectionAppLabel.Text = 'Face Detection App ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Face_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end